classdef app_regressao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InitialConditionsTable   matlab.ui.control.Table
        BoundsTable              matlab.ui.control.Table
        FactorSelectionGroup     matlab.ui.container.ButtonGroup
        ThreeFactorsRadioButton  matlab.ui.control.RadioButton
        TwoFactorsRadioButton    matlab.ui.control.RadioButton
        LimparButton             matlab.ui.control.Button
        TabeladeinputsLabel      matlab.ui.control.Label
        InputTable               matlab.ui.control.Table
        GerarPlanejamentoButton  matlab.ui.control.Button
        Results                  matlab.ui.container.Panel
        ResultsTextArea          matlab.ui.control.TextArea
        Label                    matlab.ui.control.Label
        CriarModeloButton        matlab.ui.control.Button
        CondiesiniciaisLabel     matlab.ui.control.Label
        LimitesLabel             matlab.ui.control.Label
        graficosResiduos         matlab.ui.control.UIAxes
        grafico                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotModelSurface2f(app, X1, X2, coef_details, lower_bounds, upper_bounds, optimal_point, optimal_Y)
        % Cria uma grade de pontos usando os limites inferiores e superiores
        [X1Grid, X2Grid] = meshgrid(linspace(lower_bounds(1), upper_bounds(1), 100), ...
                                    linspace(lower_bounds(2), upper_bounds(2), 100));

        % Avalia a função do modelo nos pontos da grade
        YGrid = coef_details(1) + coef_details(2)*X1Grid + coef_details(3)*X2Grid + ...
                coef_details(4)*X1Grid.^2 + coef_details(5)*X2Grid.^2 + coef_details(6)*X1Grid.*X2Grid;

        % Plotagem da superfície no componente UIAxes
        surfaceObject = surf(app.grafico, X1Grid, X2Grid, YGrid);
        xlabel(app.grafico, 'X1');
        ylabel(app.grafico, 'X2');
        zlabel(app.grafico, 'Y');
        title(app.grafico, 'Superfície de resposta gerada pelo modelo');
        colormap(app.grafico, 'hsv');
        %surfaceObject.EdgeColor = 'none';
        hold(app.grafico, 'on'); % Mantém o gráfico atual para adicionar mais elementos gráficos
        plot3(app.grafico, optimal_point(1), optimal_point(2), optimal_Y, 'b.', 'MarkerSize', 30, 'LineWidth', 6);
        hold(app.grafico, 'off'); % Desliga o modo hold
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriarModeloButton
        function CriarModeloButtonPushed2(app, event)
        
        % Obter as condições iniciais da tabela
        IC = app.InitialConditionsTable.Data;

        % Obter os limites inferiores e superiores da tabela
        lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
        upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores
   

        % Obter os valores da tabela (X1, X2, [X3]) e Y
        data = app.InputTable.Data;

        selectedButton = app.FactorSelectionGroup.SelectedObject;

        if selectedButton == app.TwoFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            Y = data(:, 3);  % Coluna Y
        
            % Chamar a função para 2 fatores
            [optimal_point, optimal_Y, coef_details, R2_vals, F_test] = gerarModelo2f(X1, X2, Y, IC, lower_bounds, upper_bounds);
            
            % Plota superfície
            plotModelSurface2f(app, X1, X2, coef_details(:,1), lower_bounds, upper_bounds, optimal_point, optimal_Y);
        elseif selectedButton == app.ThreeFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            X3 = data(:, 3); % Coluna X3
            Y = data(:, 4);  % Coluna Y
        
            % Chamar a função para 3 fatores
            [optimal_point, optimal_Y, coef_details, R2_vals, F_test, residuals] = gerarModelo3f(X1, X2, X3, Y, IC, lower_bounds, upper_bounds);
            % Plota superfície
            ...
        end
        end

        % Button pushed function: GerarPlanejamentoButton
        function GerarPlanejamentoButtonPushed(app, event)
       % Obter os limites inferiores e superiores da tabela
        lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
        upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores

        % Verificar se os limites são NaN
        if any(isnan(lower_bounds)) || any(isnan(upper_bounds))
            uialert(app.UIFigure, 'Verifique os limites inseridos. Os limites devem ser números válidos.', 'Erro nos Limites');
            return;
        end
        
        % Obter as condições iniciais da tabela
        IC = app.InitialConditionsTable.Data;
        IC = (lower_bounds + upper_bounds) / 2;

        selectedButton = app.FactorSelectionGroup.SelectedObject;
        
        if selectedButton == app.TwoFactorsRadioButton
            % Calcular os pontos ao longo das bordas (8 pontos em um quadrado)
            a = (upper_bounds(1) - lower_bounds(1))/2;
            b = (upper_bounds(2) - lower_bounds(2))/2;
            am = (upper_bounds(1) + lower_bounds(1))/2;
            bm = (upper_bounds(2) + lower_bounds(2))/2;
            star_points = [
                am - a/sqrt(2), bm - b/sqrt(2);
                am + a/sqrt(2), bm - b/sqrt(2);
                am - a/sqrt(2), bm + b/sqrt(2);
                am + a/sqrt(2), bm + b/sqrt(2);
                IC(1), lower_bounds(2);
                IC(1), upper_bounds(2);
                lower_bounds(1), IC(2);
                upper_bounds(1), IC(2);
            ];
    
            % Triplicar o ponto central
            center_points_triplicados = repmat(IC, 3, 1);
    
            % Plotar os pontos no componente UIAxes
            cla(app.grafico); % Limpar o gráfico existente
            hold(app.grafico, 'on');
            plot(app.grafico, star_points(:,1), star_points(:,2), 'bo', 'MarkerSize', 8, 'LineWidth', 2); % Pontos nas bordas
            plot(app.grafico, center_points_triplicados(:,1), center_points_triplicados(:,2), 'ro', 'MarkerSize', 8, 'LineWidth', 2); % Pontos no centro
            hold(app.grafico, 'off');
        
            xlabel(app.grafico, 'X1');
            ylabel(app.grafico, 'X2');
            title(app.grafico, 'Planejamento');
            grid(app.grafico, 'on');
        elseif selectedButton == app.ThreeFactorsRadioButton
            a = (upper_bounds(1) - lower_bounds(1))/2;
            b = (upper_bounds(2) - lower_bounds(2))/2;
            c = (upper_bounds(3) - lower_bounds(3))/2;
            a2 = (1/sqrt(3))*a;
            b2 = (1/sqrt(3))*b;
            c2 = (1/sqrt(3))*c;
            am = (upper_bounds(1) + lower_bounds(1))/2;
            bm = (upper_bounds(2) + lower_bounds(2))/2;
            cm = (upper_bounds(3) + lower_bounds(3))/2;
            star_points = [
                am + a, bm, cm;
                am - a, bm, cm;
                am, bm + b, cm;
                am, bm - b, cm;
                am, bm, cm + c,
                am, bm, cm - c;
                am + a2, bm + b2, cm + c2;
                am - a2, bm + b2, cm + c2;
                am + a2, bm - b2, cm + c2;
                am + a2, bm + b2, cm - c2;
                am - a2, bm - b2, cm + c2;
                am - a2, bm + b2, cm - c2;
                am + a2, bm - b2, cm - c2;
                am - a2, bm - b2, cm - c2;
            ];
            % Triplicar o ponto central
            center_points_triplicados = repmat(IC, 3, 1);
    
            % Limpar o gráfico anterior e plotar os novos pontos
            cla(app.grafico);  % Limpa o gráfico 3D anterior
            hold(app.grafico, 'on');
            
            % Plotar os pontos estrela em azul
            plot3(app.grafico, star_points(:,1), star_points(:,2), star_points(:,3), 'bo', 'MarkerSize', 8, 'LineWidth', 2);
            
            % Plotar o ponto central triplicado em vermelho
            plot3(app.grafico, center_points_triplicados(:,1), center_points_triplicados(:,2), center_points_triplicados(:,3), 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            
            % Adicionar rótulos
            xlabel(app.grafico, 'X1');
            ylabel(app.grafico, 'X2');
            zlabel(app.grafico, 'X3');
            title(app.grafico, 'Planejamento');
            grid(app.grafico, 'on');
            hold(app.grafico, 'off');
        end
        
        end

        % Selection changed function: FactorSelectionGroup
        function FactorSelectionGroupSelectionChanged(app, event)
        selectedButton = app.FactorSelectionGroup.SelectedObject;
            
        if selectedButton == app.TwoFactorsRadioButton
        % Configurar a tabela para 2 fatores (X1 e X2) + Y
        app.InputTable.ColumnName = {'X1', 'X2', 'Y'};
        app.InputTable.Data = zeros(11, 3); % Ajustar número de colunas para 3
        % Configurar a tabela de condições iniciais para 2 fatores (X1 e X2)
        app.InitialConditionsTable.ColumnName = {'X1', 'X2'};
        app.InitialConditionsTable.Data = zeros(1, 2); % 1 linha para condições iniciais
        app.BoundsTable.ColumnName = {'X1', 'X2'};
        app.BoundsTable.RowName = {'Superiores', 'Inferiores'};
        app.BoundsTable.Data = zeros(2, 2); % 2 linhas: 1 para limites inferiores, 1 para superiores
        elseif selectedButton == app.ThreeFactorsRadioButton
        % Configurar a tabela para 3 fatores (X1, X2 e X3) + Y
        app.InputTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
        app.InputTable.Data = zeros(19, 4); % Ajustar número de colunas para 4
        % Configurar a tabela de condições iniciais para 3 fatores (X1, X2 e X3)
        app.InitialConditionsTable.ColumnName = {'X1', 'X2', 'X3'};
        app.InitialConditionsTable.Data = zeros(1, 3); % 1 linha para condições iniciais
        % Configurar a tabela de limites para 3 fatores (X1, X2 e X3)
        app.BoundsTable.ColumnName = {'X1', 'X2', 'X3'};
        app.BoundsTable.RowName = {'Lower Bounds', 'Upper Bounds'};
        app.BoundsTable.Data = zeros(2, 3); % 2 linhas: 1 para limites inferiores, 1 para superiores
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Superfície de Resposta')
            xlabel(app.grafico, 'X1')
            ylabel(app.grafico, 'X2')
            zlabel(app.grafico, 'Y')
            app.graficosResiduos = uiaxes(app.UIFigure);
            title(app.graficosResiduos, 'Resíduos')
            xlabel(app.graficosResiduos, 'Y')
            ylabel(app.graficosResiduos, 'Resíduos')
            zlabel(app.graficosResiduos, 'Z')
            app.LimitesLabel = uilabel(app.UIFigure);
            app.LimitesLabel.Text = 'Limites:';
            app.CondiesiniciaisLabel = uilabel(app.UIFigure);
            app.CondiesiniciaisLabel.Text = 'Condições iniciais:';
            app.CriarModeloButton = uibutton(app.UIFigure, 'push');
            app.CriarModeloButton.ButtonPushedFcn = createCallbackFcn(app, @CriarModeloButtonPushed2, true);
            app.CriarModeloButton.Text = 'Criar Modelo';
            app.Results = uipanel(app.UIFigure);
            app.Results.Visible = 'off';
            app.Label = uilabel(app.Results);
            app.Label.Text = '';
            app.ResultsTextArea = uitextarea(app.Results);
            app.GerarPlanejamentoButton = uibutton(app.UIFigure, 'push');
            app.GerarPlanejamentoButton.ButtonPushedFcn = createCallbackFcn(app, @GerarPlanejamentoButtonPushed, true);
            app.GerarPlanejamentoButton.Text = 'Gerar Planejamento';
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = '';
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = true;
            app.TabeladeinputsLabel = uilabel(app.UIFigure);
            app.TabeladeinputsLabel.Text = 'Tabela de inputs ';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.Text = 'Limpar';
            app.FactorSelectionGroup = uibuttongroup(app.UIFigure);
            app.FactorSelectionGroup.SelectionChangedFcn = createCallbackFcn(app, @FactorSelectionGroupSelectionChanged, true);
            app.FactorSelectionGroup.Title = 'Fatores';
            app.TwoFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.TwoFactorsRadioButton.Text = '2 fatores';
            app.TwoFactorsRadioButton.Value = true;
            app.ThreeFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.ThreeFactorsRadioButton.Text = '3 fatores';
            app.BoundsTable = uitable(app.UIFigure);
            app.BoundsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BoundsTable.RowName = {};
            app.BoundsTable.ColumnEditable = true;
            app.InitialConditionsTable = uitable(app.UIFigure);
            app.InitialConditionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InitialConditionsTable.RowName = {};
            app.InitialConditionsTable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_regressao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end